classdef DataRetriever < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        QuickCLiCDataRetriverPanel  matlab.ui.container.Panel
        StartDateDatePickerLabel    matlab.ui.control.Label
        StartDateDatePicker         matlab.ui.control.DatePicker
        ExecuteButton_2             matlab.ui.control.Button
        DataTypeDropDownLabel       matlab.ui.control.Label
        DataTypeDropDown            matlab.ui.control.DropDown
        ResearcherCheckBox          matlab.ui.control.CheckBox
        EditField                   matlab.ui.control.EditField
        SNRCheckBox                 matlab.ui.control.CheckBox
        FocusCheckBox               matlab.ui.control.CheckBox
        ContaminantsCheckBox        matlab.ui.control.CheckBox
        AnalysisTypeCheckBox        matlab.ui.control.CheckBox
        EndDateDatePickerLabel      matlab.ui.control.Label
        EndDateDatePicker           matlab.ui.control.DatePicker
        DropDown                    matlab.ui.control.DropDown
        AvgRadiusCheckBox           matlab.ui.control.CheckBox
        EditField_2                 matlab.ui.control.NumericEditField
        toEditFieldLabel            matlab.ui.control.Label
        toEditField                 matlab.ui.control.NumericEditField
        EditField_3                 matlab.ui.control.NumericEditField
        toEditField_2Label          matlab.ui.control.Label
        toEditField_2               matlab.ui.control.NumericEditField
        EditField_4                 matlab.ui.control.NumericEditField
        toEditField_3Label          matlab.ui.control.Label
        toEditField_3               matlab.ui.control.NumericEditField
        EditField_5                 matlab.ui.control.NumericEditField
        toEditField_4Label          matlab.ui.control.Label
        toEditField_4               matlab.ui.control.NumericEditField
        Lamp_3                      matlab.ui.control.Lamp
        ConnectionPanel             matlab.ui.container.Panel
        DisconnectButton            matlab.ui.control.Button
        ConnectButton               matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        LoginasTextAreaLabel        matlab.ui.control.Label
        LoginasTextArea             matlab.ui.control.TextArea
        CustomQueryPanel            matlab.ui.container.Panel
        QueryTextArea               matlab.ui.control.TextArea
        Lamp_2                      matlab.ui.control.Lamp
        ExecuteButton               matlab.ui.control.Button
        QuickActionsPanel           matlab.ui.container.Panel
        NewMemberButton             matlab.ui.control.Button
        MemberStatusButton          matlab.ui.control.Button
        NewProtocolButton           matlab.ui.control.Button
        NewExperimentButton         matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        OutputTab                   matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        HisotryTab                  matlab.ui.container.Tab
        UITable2                    matlab.ui.control.Table
        ExportButton                matlab.ui.control.Button
        FeedbackPanel               matlab.ui.container.Panel
        validationTextArea          matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            prompt = {'Enter username:','Enter password:'};
            title = 'Login Information';
            dims = [1 35];
            definput = {'cs421g68',''};
            answer = inputdlg(prompt,title,dims,definput);
            if ~isempty(answer)
                try
                    conn = database('cs421',answer{1},answer{2}, ...
                'org.postgresql.Driver','jdbc:postgresql://comp421.cs.mcgill.ca:5432/cs421');
                    app.Lamp.Color = 'green';
                    setappdata(app.UIFigure,'conn',conn);
                    app.ConnectButton.Visible = false;
                    app.DisconnectButton.Visible = true;
                    app.LoginasTextArea.Value = answer{1};
                    app.ExecuteButton.Enable = true;
                    app.ExecuteButton_2.Enable = true;
                catch e
                    app.validationTextArea.Value = e.message;
                end
            end

        end

        % Button pushed function: DisconnectButton
        function closeButtonPushed2(app, event)
            conn = getappdata(app.UIFigure,'conn');
            close(conn);
            app.Lamp.Color = 'red';
            app.ConnectButton.Visible = true;
            app.DisconnectButton.Visible = false;
            app.LoginasTextArea.Value = '';
            app.ExecuteButton.Enable = false;
            app.ExecuteButton_2.Enable = false;
        end

        % Callback function
        function QueryTextAreaValueChanged(app, event)
            
            
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            value = app.QueryTextArea.Value;
            conn = getappdata(app.UIFigure,'conn');
            try
                query = value{1};
                if strcmp(lower(query(1:6)),'select')
                    data = select(conn,value{1});
                    app.UITable2.Data = app.UITable.Data;
                    app.UITable2.ColumnName = app.UITable.ColumnName; 
                    app.UITable.Data = data;
                    app.UITable.ColumnName = data.Properties.VariableNames;
                else
                    execute(conn,value{1})
                end
                app.validationTextArea.Value = 'Operation Successful';
                app.Lamp_2.Color = 'green';
            catch e
                app.validationTextArea.Value = e.message;
                app.Lamp_2.Color = 'red';
            end
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            value = app.DataTypeDropDown.Value;
            if strcmp(value,'DNA Unwinding')
                 app.AnalysisTypeCheckBox.Visible = true;
                 app.DropDown.Visible = true;
                 app.AvgRadiusCheckBox.Visible = false;
                 app.EditField_5.Visible = false;
                 app.toEditField_4.Visible = false;
            elseif strcmp(value,'Nanoparticle')
                 app.AnalysisTypeCheckBox.Visible = false;
                 app.DropDown.Visible = false;
                 app.AvgRadiusCheckBox.Visible = true;
                 app.EditField_5.Visible = true;
                 app.toEditField_4.Visible = true;
                
            end
        end

        % Value changed function: ResearcherCheckBox
        function ResearcherCheckBoxValueChanged(app, event)
            value = app.ResearcherCheckBox.Value;
            if value == 1
                app.EditField.Enable = true;
            else
                app.EditField.Enable = false;
            end
        end

        % Value changed function: SNRCheckBox
        function SNRCheckBoxValueChanged(app, event)
            value = app.SNRCheckBox.Value;
            if value == 1
                app.EditField_2.Enable = true;
                app.toEditField.Enable = true;
            else
                app.EditField_2.Enable = false;
                app.toEditField.Enable = false;
            end
        end

        % Value changed function: FocusCheckBox
        function FocusCheckBoxValueChanged(app, event)
            value = app.FocusCheckBox.Value;
            if value == 1
                app.EditField_3.Enable = true;
                app.toEditField_2.Enable = true;
            else
                app.EditField_3.Enable = false;
                app.toEditField_2.Enable = false;
            end
        end

        % Value changed function: ContaminantsCheckBox
        function ContaminantsCheckBoxValueChanged(app, event)
            value = app.ContaminantsCheckBox.Value;
            if value == 1
                app.EditField_4.Enable = true;
                app.toEditField_3.Enable = true;
            else
                app.EditField_4.Enable = false;
                app.toEditField_3.Enable = false;
            end
        end

        % Value changed function: AvgRadiusCheckBox
        function AvgRadiusCheckBoxValueChanged(app, event)
            value = app.AvgRadiusCheckBox.Value;
            if value == 1
                app.EditField_5.Enable = true;
                app.toEditField_4.Enable = true;
            else
                app.EditField_5.Enable = false;
                app.toEditField_4.Enable = false;
            end
        end

        % Value changed function: AnalysisTypeCheckBox
        function AnalysisTypeCheckBoxValueChanged(app, event)
            value = app.AnalysisTypeCheckBox.Value;
            if value == 1
                app.DropDown.Enable = true;
            else
                app.DropDown.Enable = false;
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            data = app.UITable.Data;
            [file,path] = uiputfile('*.csv');
            cd(path);
            writetable(data,file,'Delimiter',',')
        end

        % Button pushed function: NewProtocolButton
        function NewProtocolButtonPushed(app, event)
            conn = getappdata(app.UIFigure,'conn');
            prompt = {'Enter protocol ID:','Enter PATH:','Enter researcher'};
            title = 'New protocol Information';
            dims = [1 50];
            definput = {'2000','C\:protocol\2000.pdf','Kim Metera'};
            answer = inputdlg(prompt,title,dims,definput);
            qeury = ['INSERT INTO protocol VALUES (',answer{1},',''',answer{2},...
                    ''',''',answer{3},''')'];
            try 
                execute(conn, qeury);
                app.validationTextArea.Value = 'Operation Successful';
            catch e
                app.validationTextArea.Value = e.message;
            end
        end

        % Button pushed function: NewExperimentButton
        function NewExperimentButtonPushed(app, event)
            conn = getappdata(app.UIFigure,'conn');
            prompt = {'Enter protocol ID:','Enter try number:',...
                        'Enter microscope ID', 'Enter data ID', 'Enter researcher'};
            title = 'New Experiment Information';
            dims = [1 80];
            definput = {'2000','2','Nikon01','120','Kim Metera'};
            answer = inputdlg(prompt,title,dims,definput);
            qeury = ['INSERT INTO experiment VALUES (',answer{1},',',answer{2},...
                    ',''',answer{3},''',',answer{4},',''',answer{5},''')'];
            try 
                execute(conn, qeury);
                app.validationTextArea.Value = 'Operation Successful';
            catch e
                app.validationTextArea.Value = e.message;
            end
        end

        % Button pushed function: MemberStatusButton
        function MemberStatusButtonPushed(app, event)
            conn = getappdata(app.UIFigure,'conn');
            prompt = {'Enter name:','Enter new status:'};
            title = 'Member Information Update';
            dims = [1 50];
            definput = {'Wendy Ji','inactive'};
            answer = inputdlg(prompt,title,dims,definput);
            
            query = ['UPDATE labmember SET status =''',answer{2},''' WHERE name =''',answer{1},''';'];
            try 
                execute(conn, query);
                app.validationTextArea.Value = 'Operation Successful';
            catch e
                app.validationTextArea.Value = e.message;
            end
        end

        % Button pushed function: ExecuteButton_2
        function ExecuteButton_2Pushed(app, event)
            conn = getappdata(app.UIFigure,'conn');
            newMap = containers.Map;
            type = app.DataTypeDropDown.Value;
            
            if ~isnat(app.StartDateDatePicker.Value) 
                startDate = app.StartDateDatePicker.Value;
                startDate = datestr(startDate, 'yyyy-mm-dd');
                newMap('startDate') = startDate;
            end
            
            if ~isnat(app.EndDateDatePicker.Value)
                endDate = app.EndDateDatePicker.Value;
                endDate = datestr(endDate, 'yyyy-mm-dd');
                newMap('endDate') = endDate;
            end
            
            if strcmp(app.EditField.Enable, 'on')
                researcher = app.EditField.Value;
                newMap('Researcher') = researcher;
            end
            
            if strcmp(app.DropDown.Enable, 'on') && strcmp(type, 'DNA Unwinding')
                analysisType = app.DropDown.Value;
                newMap('analysisType') = analysisType;
            end
            
            if strcmp(app.EditField_5.Enable, 'on') && strcmp(type, 'Nanoparticle')
                MSD_from = app.EditField_5.Value; 
                newMap('MSD_from') = MSD_from;
            end
            
            if strcmp(app.toEditField_4.Enable, 'on') && strcmp(type, 'Nanoparticle')
                MSD_to = app.toEditField_4.Value;
                newMap('MSD_to') = MSD_to;
            end
            
            if strcmp(app.EditField_2.Enable, 'on')
                SNR_from = app.EditField_2.Value;
                newMap('SNR_from') = SNR_from;
            end
            
            if strcmp(app.toEditField.Enable, 'on')
                SNR_to = app.toEditField.Value;
                newMap('SNR_to') = SNR_to;
            end
           
            if strcmp(app.EditField_3.Enable, 'on')
                focus_from = app.EditField_3.Value;
                newMap('focus_from') = focus_from;
            end
            
            if strcmp(app.toEditField_2.Enable, 'on')
                focus_to = app.toEditField_2.Value;
                newMap('focus_to') = focus_to;
            end
           
            if strcmp(app.EditField_4.Enable, 'on')
                contaminants_from = app.EditField_4.Value;
                newMap('contaminants_from') = contaminants_from;
            end
            
            if strcmp(app.toEditField_3.Enable, 'on')
                contaminants_to = app.toEditField_3.Value;
                newMap('contaminants_to') = contaminants_to;
            end
            
            if strcmp(type, 'DNA Unwinding')
                select_clause = 'r.paths, r.dates, e.name, r.analysistype, c.SNR, c.focus, c.contaminants';
                from_clause = 'Experiment e, BindingAnalysisResults r, ClicDashboard c';
            else
                select_clause = 'r.paths, r.dates, e.name, r.avgradius, c.SNR, c.focus, c.contaminants';
                from_clause = 'Experiment e, NanoparticleAnalysisResults r, ClicDashboard c';
            end
            
            
            where_clause = 'e.crid = r.crid AND r.crid = c.crid';
            
            % go through all the keys in the map to determine which tables and conditions to use
            for k = keys(newMap)
                if strcmp(k, 'startDate')
                    where_clause = [where_clause, ' AND r.dates>''', startDate, ''''];
                end
                if strcmp(k, 'endDate')
                    where_clause = [where_clause, ' AND r.dates<''', endDate, ''''];
                end
                if strcmp(k, 'Researcher')
                    where_clause = [where_clause, ' AND e.name=''', researcher, ''''];
                end
                if strcmp(k, 'analysisType')
                    where_clause = [where_clause, ' AND r.analysistype=''', analysisType, ''''];
                end
                if strcmp(k, 'MSD_from')
                    where_clause = [where_clause, ' AND r.avgradius>', num2str(MSD_from)];
                end
                if strcmp(k, 'MSD_to')
                    where_clause = [where_clause, ' AND r.avgradius<', num2str(MSD_to)];
                end
                if strcmp(k, 'SNR_from')
                    where_clause = [where_clause, ' AND c.SNR>', num2str(SNR_from)];
                end
                if strcmp(k, 'SNR_to')
                    where_clause = [where_clause, ' AND c.SNR<', num2str(SNR_to)];
                end
                if strcmp(k, 'focus_from')
                    where_clause = [where_clause, ' AND c.focus>', num2str(focus_from)];
                end
                if strcmp(k, 'focus_to')
                    where_clause = [where_clause, ' AND c.focus<', num2str(focus_to)];
                end
                if strcmp(k, 'contaminants_from')
                    where_clause = [where_clause, ' AND c.contaminants>', num2str(contaminants_from)];
                end
                if strcmp(k, 'contaminants_to')
                    where_clause = [where_clause, ' AND c.contaminants<', num2str(contaminants_to)];
                end
            end
            query = ['SELECT ', select_clause, ' FROM ', from_clause, ' WHERE ', where_clause];
           
            
            try 
                data = select(conn,query);
                app.UITable2.Data = app.UITable.Data;
                app.UITable2.ColumnName = app.UITable.ColumnName;
                
                if ~isempty(data)
                    app.UITable.Data = data;
                
                    app.UITable.ColumnName = data.Properties.VariableNames;
                
                    app.validationTextArea.Value = 'Operation Successful';
                else
                    app.UITable.Data = {'No Result Found'};
                
                    app.UITable.ColumnName = {};
                    app.validationTextArea.Value = 'No Results Found';
                end
                app.Lamp_3.Color = 'green';
            catch e
                app.validationTextArea.Value = [e.message, ' ']; 
                app.Lamp_3.Color = 'red';
            end          
        end

        % Button pushed function: NewMemberButton
        function NewMemberButtonPushed(app, event)
            conn = getappdata(app.UIFigure,'conn');
            prompt = {'Enter name:','Enter contact info:', 'Enter level'};
            title = 'New member Information';
            dims = [1 50];
            definput = {'Raffle Zhu','(514)-567-2341','Research Assistant'};
            answer = inputdlg(prompt,title,dims,definput);
            if strcmp(answer{3}, 'Research Assistant')
                qeury1 = ['INSERT INTO labmember VALUES (''',answer{1},''',''',answer{2},...
                    ''',''','active',''')'];
                query2 = ['INSERT INTO researchassistant VALUES (''',answer{1},''')'];
            else
                qeury1 = ['INSERT INTO labmember VALUES (''',answer{1},''',''',answer{2},...
                    ''',''','active',''')'];
                query2 = ['INSERT INTO researcher VALUES (''',answer{1},''',''',answer{3},''')'];
            end
            try 
                execute(conn, qeury1);
                execute(conn, query2);
                app.validationTextArea.Value = 'Operation Successful';
            catch e
                app.validationTextArea.Value = e.message;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = questdlg('Terminate GUI and Connection?',...
                        'Close Request Function',...
                        'Yes','No','Yes');
            switch selection
                case 'Yes'
                    delete(app)
                    try
                        conn = getappdata(app.UIFigure,'conn');
                        close(conn);
                    catch
                    end
                case 'No'
                    return;
            end


        end

        % Callback function
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            
            
        end

        % Callback function
        function EditField_2ValueChanged2(app, event)
            value = app.EditField_2.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged3(app, event)
            value = app.EditField_2.Value;
            app.toEditField.Limits = [value,10];
        end

        % Value changed function: toEditField
        function toEditFieldValueChanged(app, event)
            value = app.toEditField.Value;
            app.EditField_2.Limits = [0,value];
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.toEditField_2.Limits = [value,5];
        end

        % Value changed function: toEditField_2
        function toEditField_2ValueChanged(app, event)
            value = app.toEditField_2.Value;
            app.EditField_3.Limits = [0,value];
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.toEditField_3.Limits = [value,5000];
        end

        % Value changed function: toEditField_3
        function toEditField_3ValueChanged(app, event)
            value = app.toEditField_3.Value;
            app.EditField_4.Limits = [0,value];
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.toEditField_4.Limits = [value,200];
        end

        % Value changed function: toEditField_4
        function toEditField_4ValueChanged(app, event)
            value = app.toEditField_4.Value;
            app.EditField_5.Limits = [0,value];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.QuickCLiCDataRetriverPanel = uipanel(app.UIFigure);
            app.QuickCLiCDataRetriverPanel.Title = 'Quick CLiC Data Retriver';
            app.StartDateDatePickerLabel = uilabel(app.QuickCLiCDataRetriverPanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.QuickCLiCDataRetriverPanel);
            app.ExecuteButton_2 = uibutton(app.QuickCLiCDataRetriverPanel, 'push');
            app.ExecuteButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButton_2Pushed, true);
            app.ExecuteButton_2.Enable = 'off';
            app.ExecuteButton_2.Text = 'Execute';
            app.DataTypeDropDownLabel = uilabel(app.QuickCLiCDataRetriverPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.QuickCLiCDataRetriverPanel);
            app.DataTypeDropDown.Items = {'DNA Unwinding', 'Nanoparticle'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'DNA Unwinding';
            app.ResearcherCheckBox = uicheckbox(app.QuickCLiCDataRetriverPanel);
            app.ResearcherCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResearcherCheckBoxValueChanged, true);
            app.ResearcherCheckBox.Text = 'Researcher';
            app.EditField = uieditfield(app.QuickCLiCDataRetriverPanel, 'text');
            app.EditField.Enable = 'off';
            app.SNRCheckBox = uicheckbox(app.QuickCLiCDataRetriverPanel);
            app.SNRCheckBox.ValueChangedFcn = createCallbackFcn(app, @SNRCheckBoxValueChanged, true);
            app.SNRCheckBox.Text = 'SNR';
            app.FocusCheckBox = uicheckbox(app.QuickCLiCDataRetriverPanel);
            app.FocusCheckBox.ValueChangedFcn = createCallbackFcn(app, @FocusCheckBoxValueChanged, true);
            app.FocusCheckBox.Text = 'Focus';
            app.ContaminantsCheckBox = uicheckbox(app.QuickCLiCDataRetriverPanel);
            app.ContaminantsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContaminantsCheckBoxValueChanged, true);
            app.ContaminantsCheckBox.Text = 'Contaminants';
            app.AnalysisTypeCheckBox = uicheckbox(app.QuickCLiCDataRetriverPanel);
            app.AnalysisTypeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeCheckBoxValueChanged, true);
            app.AnalysisTypeCheckBox.Text = 'Analysis Type';
            app.EndDateDatePickerLabel = uilabel(app.QuickCLiCDataRetriverPanel);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.QuickCLiCDataRetriverPanel);
            app.DropDown = uidropdown(app.QuickCLiCDataRetriverPanel);
            app.DropDown.Items = {'rough', 'decent', 'accurate'};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'rough';
            app.AvgRadiusCheckBox = uicheckbox(app.QuickCLiCDataRetriverPanel);
            app.AvgRadiusCheckBox.ValueChangedFcn = createCallbackFcn(app, @AvgRadiusCheckBoxValueChanged, true);
            app.AvgRadiusCheckBox.Visible = 'off';
            app.AvgRadiusCheckBox.Text = 'Avg. Radius';
            app.EditField_2 = uieditfield(app.QuickCLiCDataRetriverPanel, 'numeric');
            app.EditField_2.Limits = [0 10];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged3, true);
            app.EditField_2.Enable = 'off';
            app.toEditFieldLabel = uilabel(app.QuickCLiCDataRetriverPanel);
            app.toEditFieldLabel.Enable = 'off';
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.QuickCLiCDataRetriverPanel, 'numeric');
            app.toEditField.Limits = [0 10];
            app.toEditField.ValueChangedFcn = createCallbackFcn(app, @toEditFieldValueChanged, true);
            app.toEditField.Enable = 'off';
            app.EditField_3 = uieditfield(app.QuickCLiCDataRetriverPanel, 'numeric');
            app.EditField_3.Limits = [0 5];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Enable = 'off';
            app.toEditField_2Label = uilabel(app.QuickCLiCDataRetriverPanel);
            app.toEditField_2Label.Enable = 'off';
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.QuickCLiCDataRetriverPanel, 'numeric');
            app.toEditField_2.Limits = [0 10];
            app.toEditField_2.ValueChangedFcn = createCallbackFcn(app, @toEditField_2ValueChanged, true);
            app.toEditField_2.Enable = 'off';
            app.EditField_4 = uieditfield(app.QuickCLiCDataRetriverPanel, 'numeric');
            app.EditField_4.Limits = [0 5000];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Enable = 'off';
            app.toEditField_3Label = uilabel(app.QuickCLiCDataRetriverPanel);
            app.toEditField_3Label.Enable = 'off';
            app.toEditField_3Label.Text = 'to';
            app.toEditField_3 = uieditfield(app.QuickCLiCDataRetriverPanel, 'numeric');
            app.toEditField_3.Limits = [0 5000];
            app.toEditField_3.ValueChangedFcn = createCallbackFcn(app, @toEditField_3ValueChanged, true);
            app.toEditField_3.Enable = 'off';
            app.EditField_5 = uieditfield(app.QuickCLiCDataRetriverPanel, 'numeric');
            app.EditField_5.Limits = [0 200];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Enable = 'off';
            app.EditField_5.Visible = 'off';
            app.toEditField_4Label = uilabel(app.QuickCLiCDataRetriverPanel);
            app.toEditField_4Label.Enable = 'off';
            app.toEditField_4Label.Visible = 'off';
            app.toEditField_4Label.Text = 'to';
            app.toEditField_4 = uieditfield(app.QuickCLiCDataRetriverPanel, 'numeric');
            app.toEditField_4.Limits = [0 200];
            app.toEditField_4.ValueChangedFcn = createCallbackFcn(app, @toEditField_4ValueChanged, true);
            app.toEditField_4.Enable = 'off';
            app.toEditField_4.Visible = 'off';
            app.Lamp_3 = uilamp(app.QuickCLiCDataRetriverPanel);
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.DisconnectButton = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed2, true);
            app.DisconnectButton.Visible = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp = uilamp(app.ConnectionPanel);
            app.LoginasTextAreaLabel = uilabel(app.ConnectionPanel);
            app.LoginasTextAreaLabel.Text = 'Login as';
            app.LoginasTextArea = uitextarea(app.ConnectionPanel);
            app.LoginasTextArea.Editable = 'off';
            app.CustomQueryPanel = uipanel(app.UIFigure);
            app.CustomQueryPanel.Title = 'Custom Query';
            app.QueryTextArea = uitextarea(app.CustomQueryPanel);
            app.Lamp_2 = uilamp(app.CustomQueryPanel);
            app.ExecuteButton = uibutton(app.CustomQueryPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Enable = 'off';
            app.ExecuteButton.Text = 'Execute';
            app.QuickActionsPanel = uipanel(app.UIFigure);
            app.QuickActionsPanel.Title = 'Quick Actions';
            app.NewMemberButton = uibutton(app.QuickActionsPanel, 'push');
            app.NewMemberButton.ButtonPushedFcn = createCallbackFcn(app, @NewMemberButtonPushed, true);
            app.NewMemberButton.Text = 'New Member';
            app.MemberStatusButton = uibutton(app.QuickActionsPanel, 'push');
            app.MemberStatusButton.ButtonPushedFcn = createCallbackFcn(app, @MemberStatusButtonPushed, true);
            app.MemberStatusButton.Text = 'Member Status';
            app.NewProtocolButton = uibutton(app.QuickActionsPanel, 'push');
            app.NewProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @NewProtocolButtonPushed, true);
            app.NewProtocolButton.Text = 'New Protocol';
            app.NewExperimentButton = uibutton(app.QuickActionsPanel, 'push');
            app.NewExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @NewExperimentButtonPushed, true);
            app.NewExperimentButton.Text = 'New Experiment';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.UITable = uitable(app.OutputTab);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.HisotryTab = uitab(app.TabGroup);
            app.HisotryTab.Title = 'Hisotry';
            app.UITable2 = uitable(app.HisotryTab);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FeedbackPanel = uipanel(app.UIFigure);
            app.FeedbackPanel.Title = 'Feedback';
            app.validationTextArea = uitextarea(app.FeedbackPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataRetriever

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end